classdef FEMGui_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        aEditFieldLabel            matlab.ui.control.Label
        a                          matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        b                          matlab.ui.control.NumericEditField
        cEditFieldLabel            matlab.ui.control.Label
        c                          matlab.ui.control.NumericEditField
        dEditFieldLabel            matlab.ui.control.Label
        d                          matlab.ui.control.NumericEditField
        ElementTypeDropDownLabel   matlab.ui.control.Label
        ElementType                matlab.ui.control.DropDown
        MeshCriteriaLabel          matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        eEditFieldLabel            matlab.ui.control.Label
        e                          matlab.ui.control.NumericEditField
        UIAxes                     matlab.ui.control.UIAxes
        GenerateButton             matlab.ui.control.Button
        ElementsEditFieldLabel     matlab.ui.control.Label
        Elements                   matlab.ui.control.NumericEditField
        NodesEditField_2Label      matlab.ui.control.Label
        Nodes                      matlab.ui.control.NumericEditField
        MeshRefinementSliderLabel  matlab.ui.control.Label
        MeshRefinementSlider       matlab.ui.control.Slider
        UIAxes_2                   matlab.ui.control.UIAxes
        Plot                       matlab.ui.control.Button
        ColorbarButtonGroup        matlab.ui.container.ButtonGroup
        Button_2                   matlab.ui.control.ToggleButton
        Button_3                   matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        NL; EL; x; y; z;
        
    end
    
    methods (Access = private)
        
        function [NL, EL] = mesh_it(a1, b1, c1, d1, e1)
        
            if(app.ElementType.Value == "2DQU4N")
                [NL, EL] = mesh_2(a1, b1, c1, d1, e1);
            elseif(app.ElementType.Value == "2DTR3N")
                [NL, EL] = mesh_mk_trig_2(a1, b1, c1, d1, e1);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            %Our mesh function code goes here
            %Note that the way the nodes are numbered here are different
            %(it goes in increasing order from left to right, bottom to
            %top
            %NL = [0 0; 1 0; 2 0; 0 1; 1 1; 2 1; 0 2; 1 2; 2 2]; %coordinates of our points
            %EL = [1 2 5 4; 2 3 6 5; 4 5 8 7; 5 6 9 8];
            app.UIAxes.cla;
            
%             [NL, EL] = mesh_it(app.a.Value, app.b.Value, app.c.Value, app.d.Value, app.e.Value);
            if(app.ElementType.Value == "2DQU4N")
                [app.NL, app.EL] = mesh_2(app.a.Value, app.b.Value, app.c.Value, app.d.Value, app.e.Value);
            elseif(app.ElementType.Value == "2DTR3N")
                [app.NL, app.EL] = mesh_mk_trig_2(app.a.Value, app.b.Value, app.c.Value, app.d.Value, app.e.Value);
            end
            
            NoN = size(NL, 1);
            NoE = size(EL, 1);
            NPE = size(EL, 2);
            
            for i = 1:NoE
                nl = EL(i, 1:NPE);
                for j = 1:NPE
                    app.x(j,i) = NL(nl(j), 1);
                    app.y(j,i) = NL(nl(j), 2);
                    
                end
            end
            
            app.z = zeros(size(app.x,1), size(app.x,2));
            
            patch(app.UIAxes, app.x, app.y, app.z, 'FaceColor', 'none');
            
            app.Elements.Value = NoE;
            app.Nodes.Value = NoN;
            
            %allows us to save data because other functions can't access
            %data from this function
%             save('EL.mat', 'EL');
%             save('NL.mat', 'NL');
%             save('x.mat', 'x');
%             save('y.mat', 'y');
%             save('z.mat', 'z');
%             
            
        end

        % Value changed function: MeshRefinementSlider
        function MeshRefinementSliderValueChanged(app, event)
            value = app.MeshRefinementSlider.Value;
            
            
            app.UIAxes.cla;
            app.UIAxes_2.cla;
%             [NL, EL] = mesh_it(app.a.Value, app.b.Value, round(value), round(value), app.e.Value);
            
            if(app.ElementType.Value == "2DQU4N")
                [app.NL, app.EL] = mesh_2(app.a.Value, app.b.Value, round(value), round(value), app.e.Value);
            elseif(app.ElementType.Value == "2DTR3N")
                [app.NL, app.EL] = mesh_mk_trig_2(app.a.Value, app.b.Value, round(value), round(value), app.e.Value);
            end
            
            
            NoN = size(app.NL, 1);
            NoE = size(app.EL, 1);
            NPE = size(app.EL, 2);
            
            for i = 1:NoE
                nl = EL(i, 1:NPE);
                for j = 1:NPE
                    x(j,i) = NL(nl(j), 1);
                    y(j,i) = NL(nl(j), 2);
                    
                end
            end
            
            z = zeros(size(x,1), size(x,2));
            
            patch(app.UIAxes, x, y, z, 'FaceColor', 'none');
            
            app.Elements.Value = NoE;
            app.Nodes.Value = NoN;
            
            %%% This part does the mesh for the coloured graph
            for i = 1:NoE
                nl = EL(i, 1:NPE);
                for j = 1:NPE
                    x(j,i) = NL(nl(j), 1);
                    y(j,i) = NL(nl(j), 2);
                    z(j, i) = 0;
                    c(j, i) = x(j, i);
                    
                end
            end
            
            patch(app.UIAxes_2, x, y, z, c);
            
            %allows us to save data because other functions can't access
            %data from this function
%             save('EL.mat', 'EL');
%             save('NL.mat', 'NL');
%             save('x.mat', 'x');
%             save('y.mat', 'y');
%             save('z.mat', 'z');
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            
            load('EL.mat', 'EL');
            load('NL.mat', 'NL');
            
            NoN = size(NL, 1);
            NoE = size(EL, 1);
            NPE = size(EL, 2);
            
            for i = 1:NoE
                nl = EL(i, 1:NPE);
                for j = 1:NPE
                    x(j,i) = NL(nl(j), 1);
                    y(j,i) = NL(nl(j), 2);
                    z(j, i) = 0;
                    c(j, i) = x(j, i);
                    
                end
            end
            
            patch(app.UIAxes_2, x, y, z, c);
            colormap(app.UIAxes_2, 'jet');
            
            hold(app.UIAxes_2, 'on');
            set(app.UIAxes_2, 'View', [0 90]);
            
            %to set limit on color maps

            h_2 = colorbar(app.UIAxes_2);
            t=get(h_2, 'Limits');
            T = linspace(t(1), t(2), 5);
            set(h_2, 'Ticks', T);
            TL = arrayfun(@(x) sprintf('%.2f', x), T, 'un', 0);
            set(h_2, 'TickLabels', TL);


        end

        % Selection changed function: ColorbarButtonGroup
        function ColorbarButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorbarButtonGroup.SelectedObject;
            switch app.ColorbarButtonGroup.SelectedObject.Icon
                case 'jet.png'
                    colormap(app.UIAxes_2, 'jet');
                case 'parula.png'
                    colormap(app.UIAxes_2, 'parula');
            end
        end

        % Value changed function: ElementType
        function ElementTypeValueChanged(app, event)
            value = app.ElementType.Value;
            MeshRefinementSliderValueChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'numeric');
            app.a.Value = 1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.UIFigure, 'numeric');
            app.b.Value = 1;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.c = uieditfield(app.UIFigure, 'numeric');
            app.c.Value = 5;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.d = uieditfield(app.UIFigure, 'numeric');
            app.d.Value = 5;
            app.ElementTypeDropDownLabel = uilabel(app.UIFigure);
            app.ElementTypeDropDownLabel.Text = 'Element Type';
            app.ElementType = uidropdown(app.UIFigure);
            app.ElementType.Items = {'2DTR3N', '2DQU4N'};
            app.ElementType.ValueChangedFcn = createCallbackFcn(app, @ElementTypeValueChanged, true);
            app.ElementType.Value = '2DTR3N';
            app.MeshCriteriaLabel = uilabel(app.UIFigure);
            app.MeshCriteriaLabel.Text = 'Mesh Criteria';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'circle.png';
            app.Button.Text = '';
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.e = uieditfield(app.UIFigure, 'numeric');
            app.e.Value = 0.2;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ElementsEditFieldLabel = uilabel(app.UIFigure);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.Elements = uieditfield(app.UIFigure, 'numeric');
            app.Elements.Editable = 'off';
            app.NodesEditField_2Label = uilabel(app.UIFigure);
            app.NodesEditField_2Label.Text = '#Nodes';
            app.Nodes = uieditfield(app.UIFigure, 'numeric');
            app.Nodes.Editable = 'off';
            app.MeshRefinementSliderLabel = uilabel(app.UIFigure);
            app.MeshRefinementSliderLabel.Text = 'Mesh Refinement';
            app.MeshRefinementSlider = uislider(app.UIFigure);
            app.MeshRefinementSlider.Limits = [0 20];
            app.MeshRefinementSlider.ValueChangedFcn = createCallbackFcn(app, @MeshRefinementSliderValueChanged, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Plot = uibutton(app.UIFigure, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.ColorbarButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorbarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorbarButtonGroupSelectionChanged, true);
            app.ColorbarButtonGroup.Title = 'Colorbar';
            app.Button_2 = uitogglebutton(app.ColorbarButtonGroup);
            app.Button_2.Icon = 'jet.png';
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.Button_3 = uitogglebutton(app.ColorbarButtonGroup);
            app.Button_3.Icon = 'parula.png';
            app.Button_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEMGui_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end